classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Measurement2Button  matlab.ui.control.Button
        Measurement1Button  matlab.ui.control.Button
        CalibrateButton     matlab.ui.control.Button
        ConnectButton       matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
        offset
        phase1
        amp1
        phase2
        amp2


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            clear;

            %connect to serial, re-run untill light tunrs on
            arduino = serialport("COM17", 115200);
            configureTerminator(arduino,"LF");
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.offset = calibrate(arduino)
        end

        % Button pushed function: Measurement1Button
        function Measurement1ButtonPushed(app, event)
            [app.phase1, app.amp1] = collect_sample(arduino, 0, false);
            polarplot(app.UIAxes, [0 app.phase1], [0 app.amp1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.Measurement1Button = uibutton(app.UIFigure, 'push');
            app.Measurement1Button.ButtonPushedFcn = createCallbackFcn(app, @Measurement1ButtonPushed, true);
            app.Measurement1Button.Text = 'Measurement 1';
            app.Measurement2Button = uibutton(app.UIFigure, 'push');
            app.Measurement2Button.Text = 'Measurement 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end